classdef rtgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout2                    matlab.ui.container.GridLayout
        TabGroup2                      matlab.ui.container.TabGroup
        PlotsTab                       matlab.ui.container.Tab
        GridLayout4                    matlab.ui.container.GridLayout
        TimeScope                      matlab.ui.scope.TimeScope
        GridLayout15                   matlab.ui.container.GridLayout
        TimeScalesSlider               matlab.ui.control.Slider
        TimeScalesSliderLabel          matlab.ui.control.Label
        DataTab                        matlab.ui.container.Tab
        GridLayout11                   matlab.ui.container.GridLayout
        UITable                        matlab.ui.control.Table
        SimulationSettingsPanel        matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        SimulationControls             simulink.ui.control.SimulationControls
        GridLayout7                    matlab.ui.container.GridLayout
        IntervalStepsSpinner           matlab.ui.control.Spinner
        IntervalStepsSpinnerLabel      matlab.ui.control.Label
        OperationModeDropDown          matlab.ui.control.DropDown
        OperationModeDropDownLabel     matlab.ui.control.Label
        SimulationProgress             simulink.ui.control.SimulationProgress
        TabGroup                       matlab.ui.container.TabGroup
        ControlTab                     matlab.ui.container.Tab
        GridLayout5                    matlab.ui.container.GridLayout
        SignalGeneratorPanel           matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        GridLayout9_3                  matlab.ui.container.GridLayout
        MeasurementNoiseDropDown       matlab.ui.control.DropDown
        MeasurementNoiseDropDownLabel  matlab.ui.control.Label
        TabGroup_DistSig               matlab.ui.container.TabGroup
        TabDistConstant                matlab.ui.container.Tab
        GridLayout10_5                 matlab.ui.container.GridLayout
        openclosedLabel                matlab.ui.control.Label
        ValueEditField_2               matlab.ui.control.NumericEditField
        ValueEditField_2Label          matlab.ui.control.Label
        TabDistSine                    matlab.ui.container.Tab
        GridLayout10_6                 matlab.ui.container.GridLayout
        DegreesLabel                   matlab.ui.control.Label
        openclosedLabel_3              matlab.ui.control.Label
        HzLabel_4                      matlab.ui.control.Label
        openclosedLabel_2              matlab.ui.control.Label
        PhaseEditField                 matlab.ui.control.NumericEditField
        PhaseEditFieldLabel            matlab.ui.control.Label
        OffsetEditField_3              matlab.ui.control.NumericEditField
        OffsetEditField_3Label         matlab.ui.control.Label
        FrequencyEditField_2           matlab.ui.control.NumericEditField
        FrequencyEditField_2Label      matlab.ui.control.Label
        AmplitudeEditField_4           matlab.ui.control.NumericEditField
        AmplitudeEditField_4Label      matlab.ui.control.Label
        TabDistSquare                  matlab.ui.container.Tab
        GridLayout10_7                 matlab.ui.container.GridLayout
        SecondsLabel_4                 matlab.ui.control.Label
        openclosedLabel_5              matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        SecondsLabel_3                 matlab.ui.control.Label
        openclosedLabel_4              matlab.ui.control.Label
        DelayEditField                 matlab.ui.control.NumericEditField
        DelayEditFieldLabel            matlab.ui.control.Label
        OffsetEditField_4              matlab.ui.control.NumericEditField
        OffsetEditField_4Label         matlab.ui.control.Label
        DutyEditField_2                matlab.ui.control.NumericEditField
        DutyEditField_2Label           matlab.ui.control.Label
        PeriodEditField_2              matlab.ui.control.NumericEditField
        PeriodEditField_2Label         matlab.ui.control.Label
        AmplitudeEditField_5           matlab.ui.control.NumericEditField
        AmplitudeEditField_5Label      matlab.ui.control.Label
        TabDistNoise                   matlab.ui.container.Tab
        GridLayout10_8                 matlab.ui.container.GridLayout
        VarianceEditField              matlab.ui.control.NumericEditField
        VarianceEditFieldLabel         matlab.ui.control.Label
        NoisePowerEditField            matlab.ui.control.NumericEditField
        NoisePowerEditFieldLabel       matlab.ui.control.Label
        GridLayout9_2                  matlab.ui.container.GridLayout
        DistrubanceSwitch              matlab.ui.control.RockerSwitch
        DisturbanceDropDown            matlab.ui.control.DropDown
        DisturbanceDropDownLabel       matlab.ui.control.Label
        TabGroup_InputSignal           matlab.ui.container.TabGroup
        TabSigConstant                 matlab.ui.container.Tab
        GridLayout10                   matlab.ui.container.GridLayout
        VoltsLabel                     matlab.ui.control.Label
        ConstantValueEditField         matlab.ui.control.NumericEditField
        ValueEditFieldLabel            matlab.ui.control.Label
        TabSigSine                     matlab.ui.container.Tab
        GridLayout10_2                 matlab.ui.container.GridLayout
        VoltsLabel_4                   matlab.ui.control.Label
        HzLabel                        matlab.ui.control.Label
        VoltsLabel_3                   matlab.ui.control.Label
        OffsetEditField                matlab.ui.control.NumericEditField
        OffsetEditFieldLabel           matlab.ui.control.Label
        FrequencyEditField             matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel        matlab.ui.control.Label
        AmplitudeEditField             matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel        matlab.ui.control.Label
        TabSigSquare                   matlab.ui.container.Tab
        GridLayout10_3                 matlab.ui.container.GridLayout
        VoltsLabel_6                   matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        SecondsLabel                   matlab.ui.control.Label
        VoltsLabel_5                   matlab.ui.control.Label
        OffsetEditField_2              matlab.ui.control.NumericEditField
        OffsetEditField_2Label         matlab.ui.control.Label
        DutyEditField                  matlab.ui.control.NumericEditField
        DutyEditFieldLabel             matlab.ui.control.Label
        PeriodEditField                matlab.ui.control.NumericEditField
        PeriodEditFieldLabel           matlab.ui.control.Label
        AmplitudeEditField_2           matlab.ui.control.NumericEditField
        AmplitudeEditField_2Label      matlab.ui.control.Label
        TabSigChirp                    matlab.ui.container.Tab
        GridLayout10_4                 matlab.ui.container.GridLayout
        SecondsLabel_2                 matlab.ui.control.Label
        HzLabel_3                      matlab.ui.control.Label
        HzLabel_2                      matlab.ui.control.Label
        VoltsLabel_7                   matlab.ui.control.Label
        ChirpTimeEditField             matlab.ui.control.NumericEditField
        ChirpTimeEditFieldLabel        matlab.ui.control.Label
        EndFrequencyEditField          matlab.ui.control.NumericEditField
        EndFrequencyEditFieldLabel     matlab.ui.control.Label
        StartFrequencyEditField        matlab.ui.control.NumericEditField
        StartFrequencyEditFieldLabel   matlab.ui.control.Label
        AmplitudeEditField_3           matlab.ui.control.NumericEditField
        AmplitudeEditField_3Label      matlab.ui.control.Label
        TabSigArbitrary                matlab.ui.container.Tab
        GridLayout9                    matlab.ui.container.GridLayout
        InputSignalSwitch              matlab.ui.control.RockerSwitch
        InputSignalTypeDropDown        matlab.ui.control.DropDown
        InputSignalTypeDropDownLabel   matlab.ui.control.Label
        SystemConfigurationPanel       matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        FeedbackDropDown               matlab.ui.control.DropDown
        FeedbackDropDownLabel          matlab.ui.control.Label
        ConfigTab                      matlab.ui.container.Tab
        GridLayout12                   matlab.ui.container.GridLayout
        CommunicationPanel             matlab.ui.container.Panel
        GridLayout14                   matlab.ui.container.GridLayout
        COMportDropDown                matlab.ui.control.DropDown
        COMportDropDownLabel           matlab.ui.control.Label
        CalibrationSettingsPanel       matlab.ui.container.Panel
        GridLayout13                   matlab.ui.container.GridLayout
        Tank1offsetEditField           matlab.ui.control.NumericEditField
        Tank1offsetEditFieldLabel      matlab.ui.control.Label
        Tank2offsetEditField           matlab.ui.control.NumericEditField
        Tank2offsetEditFieldLabel      matlab.ui.control.Label
        Tank2scaleEditField            matlab.ui.control.NumericEditField
        Tank2scaleEditFieldLabel       matlab.ui.control.Label
        Tank1scaleEditField            matlab.ui.control.NumericEditField
        Tank1scaleEditFieldLabel       matlab.ui.control.Label
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        baseModelName = 'combined_mdl_wpanel'
        modelName = 'combined_mdl_wpanel/Coupled Tanks System';
        params = struct();
        operationMode = 0; % default to simulation
        intervalStep = 0.1;
        wkspc;
        SimulationMonitorTimer timer;
        SimulationWasRunning logical = false;
    end
    
    methods (Access = private)

        % Helper function to save sim data to workspace
        
        
        function processSimulationOutput(app)
            try
                simOut = app.Simulation.SimulationOutput;
                timestamp = datestr(now, 'yyyymmdd_HHMMSS');
        
                % Save simOut to base workspace
                simOutVarName = ['simOut_' timestamp];
                assignin('base', simOutVarName, simOut);
                disp(['Simulation output saved as: ' simOutVarName]);
        
                logsout = simOut.logsout;
                loggedSignals = struct();
        
                for i = 1:logsout.numElements
                    sig = logsout.getElement(i);
                    sigName = matlab.lang.makeValidName(sig.Name);
        
                    if isa(sig.Values, 'timeseries')
                        loggedSignals.(sigName) = sig.Values;
                    else
                        disp(['Skipping non-timeseries signal: ' sigName]);
                    end
                end
        
                % Save the struct to base workspace
                structVarName = ['loggedSignals_' timestamp];
                assignin('base', structVarName, loggedSignals);
                disp(['Logged signals saved as struct: ' structVarName]);
        
                % Optionally clear GUI table if not displaying
                app.UITable.Data = {};
                app.UITable.ColumnName = {};
        
            catch ME
                disp(['Error processing simulation output: ' ME.message]);
            end
        end


        function monitorSimulationStatus(app)
            try
                status = get_param(app.baseModelName, 'SimulationStatus');
        
                if strcmp(status, 'running')
                    app.SimulationWasRunning = true;
                elseif strcmp(status, 'stopped') && app.SimulationWasRunning
                    % Simulation just stopped
                    app.SimulationWasRunning = false;
        
                    % Trigger post-simulation logging
                    processSimulationOutput(app);
                end
            catch
                % Ignore errors if model is not loaded
            end
        end

        
        % Helper function to construct parameter paths
        function addParam(app, paramName, blockSubPath, blockParam)
            app.params.(paramName).BlockPath = [app.modelName '/' blockSubPath];
            app.params.(paramName).ParamName = blockParam;
        end

        % Helper function to set parameter
        function updateParam(app, paramName, value)
            param = app.params.(paramName);
            set_param(param.BlockPath, param.ParamName, num2str(value));
        end

        % Helper function to get parameter
        function val = getParam(app, paramName)
            param = app.params.(paramName);
            val = get_param(param.BlockPath, param.ParamName);
        end

        % Helper function to set workspace parameter
        function updateWkspcParam(app, paramName, value)
            assignin(app.wkspc, paramName, value);
        end
        
        % Helper function to set operation mode and other related
        % parameters/settings
        function setOperationMode(app, mode)
            % Force stop simulation
            set_param(app.baseModelName, 'SimulationCommand', 'stop');

            % Update Simulink model parameter
            updateParam(app, 'operation_mode', mode);

            % Update model configuration
            if mode
                % Real-time mode
                % Set to use slrdt model config
                setActiveConfigSet(app.baseModelName, 'SimulinkDesktopRealTime');
                sldrtconfigset(app.baseModelName); % reset configset to use default desktop realtime config
                updateWkspcParam(app, 'sample_period_ms', app.intervalStep*1000);
                set_param(app.baseModelName, 'FixedStep', num2str(app.intervalStep));
                updateParam(app, 'stream_in_rate', app.intervalStep);
                updateParam(app, 'stream_out_rate', app.intervalStep);
            else
                %% Simulation mode
                %set_param(app.baseModelName, 'Solver', 'VariableStepAuto', 'MaxStep', '1e-3');
                setActiveConfigSet(app.baseModelName, 'SimulinkVariableStep');
                updateParam(app, 'stream_in_rate', 0);
                updateParam(app, 'stream_out_rate', 0);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create listings for Simulink model parameters and set to
            app.wkspc = get_param(app.baseModelName,'ModelWorkspace');

            % default values.
            addParam(app, 'input_enable', 'Reference Signal/input_enable', 'Value');
            updateParam(app, 'input_enable', find(strcmp(app.InputSignalSwitch.Value, app.InputSignalSwitch.Items))-1)
            addParam(app, 'input_constant_value', 'Reference Signal/input_constant', 'Value');
            updateParam(app, 'input_constant_value', app.ConstantValueEditField.Value);
            addParam(app, 'input_mode_value', 'Reference Signal/input_mode', 'Value');
            updateParam(app, 'input_mode_value', find(strcmp(app.InputSignalTypeDropDown.Value, app.InputSignalTypeDropDown.Items)));
            addParam(app, 'input_sine_amplitude', 'Reference Signal/input_sine', 'Amplitude');
            updateParam(app, 'input_sine_amplitude', app.AmplitudeEditField.Value);
            addParam(app, 'input_sine_frequency', 'Reference Signal/input_sine', 'Frequency');
            updateParam(app, 'input_sine_frequency', app.FrequencyEditField.Value);
            addParam(app, 'input_sine_offset', 'Reference Signal/input_sine_offset', 'Value');
            updateParam(app, 'input_sine_offset', app.OffsetEditField.Value);
            addParam(app, 'input_square_amplitude', 'Reference Signal/input_square', 'Amplitude');
            updateParam(app, 'input_square_amplitude', app.AmplitudeEditField_2.Value);
            addParam(app, 'input_square_period', 'Reference Signal/input_square', 'Period');
            updateParam(app, 'input_square_period', app.PeriodEditField.Value);
            addParam(app, 'input_square_duty', 'Reference Signal/input_square', 'PulseWidth');
            updateParam(app, 'input_square_duty', app.DutyEditField.Value);
            addParam(app, 'input_square_offset', 'Reference Signal/input_square_offset', 'Value');
            updateParam(app, 'input_square_offset', app.OffsetEditField_2.Value);
            addParam(app, 'input_chirp_amplitude', 'Reference Signal/input_chirp_amplitude', 'Value');
            updateParam(app, 'input_chirp_amplitude', app.AmplitudeEditField_3.Value);
            addParam(app, 'input_chirp_f1', 'Reference Signal/input_chirp', 'f1');
            updateParam(app, 'input_chirp_f1', app.StartFrequencyEditField.Value);
            addParam(app, 'input_chirp_f2', 'Reference Signal/input_chirp', 'f2');
            updateParam(app, 'input_chirp_f2', app.EndFrequencyEditField.Value);
            addParam(app, 'input_chirp_t', 'Reference Signal/input_chirp', 'T');
            updateParam(app, 'input_chirp_t', app.ChirpTimeEditField.Value);

            addParam(app, 'dist_enable', 'Disturbance/dist_enable', 'Value');
            updateParam(app, 'dist_enable', find(strcmp(app.DistrubanceSwitch.Value, app.DistrubanceSwitch.Items))-1);
            addParam(app, 'dist_mode_value', 'Disturbance/dist_mode', 'Value');
            updateParam(app, 'dist_mode_value', find(strcmp(app.DisturbanceDropDown.Value, app.DisturbanceDropDown.Items)));

            addParam(app, 'dist_constant_value', 'Disturbance/dist_constant', 'Value');
            updateParam(app, 'dist_constant_value', app.ValueEditField_2.Value);
            addParam(app, 'dist_sine_amplitude', 'Disturbance/dist_sine', 'Amplitude');
            updateParam(app, 'dist_sine_amplitude', app.AmplitudeEditField_4.Value);
            addParam(app, 'dist_sine_frequency', 'Disturbance/dist_sine', 'Frequency');
            updateParam(app, 'dist_sine_frequency', app.FrequencyEditField_2.Value);
            addParam(app, 'dist_sine_phase', 'Disturbance/dist_sine', 'Phase');
            updateParam(app, 'dist_sine_phase', app.PhaseEditField.Value);
            addParam(app, 'dist_sine_offset', 'Disturbance/dist_sine_offset', 'Value');
            updateParam(app, 'dist_sine_offset', app.OffsetEditField_3.Value);
            addParam(app, 'dist_square_amplitude', 'Disturbance/dist_square', 'Amplitude');
            updateParam(app, 'dist_square_amplitude', app.AmplitudeEditField_5.Value);
            addParam(app, 'dist_square_period', 'Disturbance/dist_square', 'Period');
            updateParam(app, 'dist_square_period', app.PeriodEditField_2.Value);
            addParam(app, 'dist_square_duty', 'Disturbance/dist_square', 'PulseWidth');
            updateParam(app, 'dist_square_duty', app.DutyEditField_2.Value);
            addParam(app, 'dist_square_delay', 'Disturbance/dist_square', 'PhaseDelay');
            updateParam(app, 'dist_square_delay', app.DelayEditField.Value);
            addParam(app, 'dist_square_offset', 'Disturbance/dist_square_offset', 'Value');
            updateParam(app, 'dist_square_offset', app.OffsetEditField_4.Value);
            addParam(app, 'dist_noise_power', 'Disturbance/dist_noise', 'Cov');
            updateParam(app, 'dist_noise_power', app.NoisePowerEditField.Value);

            addParam(app, 'tank1_scale', 'Plant - Coupled Tanks/Coupled Tanks/Tank 1 Scale','Gain');
            updateParam(app, 'tank1_scale', app.Tank1scaleEditField.Value);
            addParam(app, 'tank1_offset', 'Plant - Coupled Tanks/Coupled Tanks/Tank 1 Offset','Value');
            updateParam(app, 'tank1_offset', app.Tank1offsetEditField.Value);
            addParam(app, 'tank2_scale', 'Plant - Coupled Tanks/Coupled Tanks/Tank 2 Scale','Gain');
            updateParam(app, 'tank2_scale', app.Tank2scaleEditField.Value);
            addParam(app, 'tank2_offset', 'Plant - Coupled Tanks/Coupled Tanks/Tank 2 Offset','Value');
            updateParam(app, 'tank2_offset', app.Tank2offsetEditField.Value);

            addParam(app, 'stream_out_port', 'Plant - Coupled Tanks/Coupled Tanks/Stream Output', 'DrvAddress');
            addParam(app, 'stream_in_port', 'Plant - Coupled Tanks/Coupled Tanks/Stream Input', 'DrvAddress');
            app.COMportDropDown.Items = extract(serialportlist('all'),digitsPattern); % Populate list of possible COM ports
            portValue = getParam(app, 'stream_out_port');
            matchIdx = find(strcmp(app.COMportDropDown.Items, portValue), 1); % Only take the first match
            if ~isempty(matchIdx)
                app.COMportDropDown.ValueIndex = matchIdx;
            else
                % Fallback to first item or handle gracefully
                app.COMportDropDown.ValueIndex = 1;
                warning('Specified COM port "%s" not found. Defaulting to first available port.', portValue);
            end

            %app.COMportDropDown.ValueIndex = find(strcmp(app.COMportDropDown.Items, getParam(app, 'stream_out_port')))
            updateParam(app, 'stream_out_port', app.COMportDropDown.Value);
            updateParam(app, 'stream_in_port', app.COMportDropDown.Value);

            addParam(app, 'stream_in_rate', 'Plant - Coupled Tanks/Coupled Tanks/Stream Input', 'SampleTime');
            addParam(app, 'stream_out_rate', 'Plant - Coupled Tanks/Coupled Tanks/Stream Output', 'SampleTime');
            addParam(app, 'operation_mode', 'Plant - Coupled Tanks/Simulink Model Configuration', 'Value');
            
            %setOperationMode(app, 0); % Set operation mode to simulation
            setOperationMode(app, 1); % Set operation mode to actual

            % Start monitoring simulation status
            app.SimulationMonitorTimer = timer( ...
                'ExecutionMode', 'fixedRate', ...
                'Period', 1, ...
                'TimerFcn', @(~,~) monitorSimulationStatus(app));
            start(app.SimulationMonitorTimer);

        end

        % Value changed function: InputSignalTypeDropDown
        function InputSignalTypeDropDownValueChanged(app, event)
            % Update Simulink model parameter
            updateParam(app, 'input_mode_value', find(strcmp(app.InputSignalTypeDropDown.Value, app.InputSignalTypeDropDown.Items)));
            % Update Tab control view
            tabToSelect = findobj(app.TabGroup_InputSignal, 'Title', app.InputSignalTypeDropDown.Value);
            if ~isempty(tabToSelect)
                app.TabGroup_InputSignal.SelectedTab = tabToSelect; % Set the selected tab
            end
        end

        % Value changed function: ConstantValueEditField
        function ConstantValueEditFieldValueChanged(app, event)
            % Update Simulink model parameter
            updateParam(app, 'input_constant_value', app.ConstantValueEditField.Value);
        end

        % Value changed function: AmplitudeEditField
        function AmplitudeEditFieldValueChanged(app, event)
            % Update Simulink model parameter
            updateParam(app, 'input_sine_amplitude', app.AmplitudeEditField.Value);
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            % Update Simulink model parameter
            updateParam(app, 'input_sine_frequency', app.FrequencyEditField.Value);
        end

        % Value changed function: OffsetEditField
        function OffsetEditFieldValueChanged(app, event)
            % Update Simulink model parameter
            updateParam(app, 'input_sine_offset', app.OffsetEditField.Value);
        end

        % Value changed function: AmplitudeEditField_2
        function AmplitudeEditField_2ValueChanged(app, event)
            % Update Simulink model parameter
            updateParam(app, 'input_square_amplitude', app.AmplitudeEditField_2.Value);
        end

        % Value changed function: PeriodEditField
        function PeriodEditFieldValueChanged(app, event)
            % Update Simulink model parameter
            updateParam(app, 'input_square_period', app.PeriodEditField.Value);
        end

        % Value changed function: DutyEditField
        function DutyEditFieldValueChanged(app, event)
            % Update Simulink model parameter
            updateParam(app, 'input_square_duty', app.DutyEditField.Value);
        end

        % Value changed function: OffsetEditField_2
        function OffsetEditField_2ValueChanged(app, event)
            % Update Simulink model parameter
            updateParam(app, 'input_square_offset', app.OffsetEditField_2.Value);
        end

        % Value changed function: AmplitudeEditField_3
        function AmplitudeEditField_3ValueChanged(app, event)
            % Update Simulink model parameter
            updateParam(app, 'input_chirp_amplitude', app.AmplitudeEditField_3.Value);
        end

        % Value changed function: StartFrequencyEditField
        function StartFrequencyEditFieldValueChanged(app, event)
            % Update Simulink model parameter
            updateParam(app, 'input_chirp_f1', app.StartFrequencyEditField.Value);
        end

        % Value changed function: EndFrequencyEditField
        function EndFrequencyEditFieldValueChanged(app, event)
            % Update Simulink model parameter
            updateParam(app, 'input_chirp_f2', app.EndFrequencyEditField.Value);
        end

        % Value changed function: ChirpTimeEditField
        function ChirpTimeEditFieldValueChanged(app, event)
            % Update Simulink model parameter
            updateParam(app, 'input_chirp_t', app.ChirpTimeEditField.Value);
        end

        % Value changed function: OperationModeDropDown
        function OperationModeDropDownValueChanged(app, event)
            SetMode = find(strcmp(app.OperationModeDropDown.Value,app.OperationModeDropDown.Items)) - 1;
            if SetMode ~= app.operationMode
                setOperationMode(app, SetMode); % Set operation mode to simulation
            end
        end

        % Value changed function: InputSignalSwitch
        function InputSignalSwitchValueChanged(app, event)
            updateParam(app, 'input_enable', find(strcmp(app.InputSignalSwitch.Value, app.InputSignalSwitch.Items))-1);
        end

        % Value changed function: DistrubanceSwitch
        function DistrubanceSwitchValueChanged(app, event)
            updateParam(app, 'dist_enable', find(strcmp(app.DistrubanceSwitch.Value, app.DistrubanceSwitch.Items))-1);
        end

        % Value changed function: Tank1scaleEditField
        function Tank1scaleEditFieldValueChanged(app, event)
            updateParam(app, 'tank1_scale', app.Tank1scaleEditField.Value);
        end

        % Value changed function: Tank1offsetEditField
        function Tank1offsetEditFieldValueChanged(app, event)
            updateParam(app, 'tank1_offset', app.Tank1offsetEditField.Value);
        end

        % Value changed function: Tank2scaleEditField
        function Tank2scaleEditFieldValueChanged(app, event)
            updateParam(app, 'tank2_scale', app.Tank2scaleEditField.Value);
        end

        % Value changed function: Tank2offsetEditField
        function Tank2offsetEditFieldValueChanged(app, event)
            updateParam(app, 'tank2_offset', app.Tank2offsetEditField.Value);
        end

        % Value changed function: COMportDropDown
        function COMportDropDownValueChanged(app, event)
            updateParam(app, 'stream_out_port', app.COMportDropDown.Value);
            updateParam(app, 'stream_in_port', app.COMportDropDown.Value);
        end

        % Value changed function: TimeScalesSlider
        function TimeScalesSliderValueChanged(app, event)
            value = app.TimeScalesSlider.Value;
            app.TimeScope.XTimeSpan = value;
        end

        % Value changed function: DisturbanceDropDown
        function DisturbanceDropDownValueChanged(app, event)
            % Update Simulink model parameter
            updateParam(app, 'dist_mode_value', find(strcmp(app.DisturbanceDropDown.Value, app.DisturbanceDropDown.Items)));
            % Update Tab control view
            tabToSelect = findobj(app.TabGroup_DistSig, 'Title', app.DisturbanceDropDown.Value);
            if ~isempty(tabToSelect)
                app.TabGroup_DistSig.SelectedTab = tabToSelect; % Set the selected tab
            end
            
        end

        % Value changed function: ValueEditField_2
        function ValueEditField_2ValueChanged(app, event)
            updateParam(app, 'dist_constant_value', app.ValueEditField_2.Value);
        end

        % Value changed function: AmplitudeEditField_4
        function AmplitudeEditField_4ValueChanged(app, event)
            updateParam(app, 'dist_sine_amplitude', app.AmplitudeEditField_4.Value);
        end

        % Value changed function: FrequencyEditField_2
        function FrequencyEditField_2ValueChanged(app, event)
            updateParam(app, 'dist_sine_frequency', app.FrequencyEditField_2.Value);
        end

        % Value changed function: PhaseEditField
        function PhaseEditFieldValueChanged(app, event)
            updateParam(app, 'dist_sine_phase', app.PhaseEditField.Value);
        end

        % Value changed function: OffsetEditField_3
        function OffsetEditField_3ValueChanged(app, event)
            updateParam(app, 'dist_sine_offset', app.OffsetEditField_3.Value);
        end

        % Value changed function: AmplitudeEditField_5
        function AmplitudeEditField_5ValueChanged(app, event)
            updateParam(app, 'dist_square_amplitude', app.AmplitudeEditField_5.Value);
        end

        % Value changed function: PeriodEditField_2
        function PeriodEditField_2ValueChanged(app, event)
            updateParam(app, 'dist_square_period', app.PeriodEditField_2.Value);
        end

        % Value changed function: DutyEditField_2
        function DutyEditField_2ValueChanged(app, event)
            updateParam(app, 'dist_square_duty', app.DutyEditField_2.Value);
        end

        % Value changed function: DelayEditField
        function DelayEditFieldValueChanged(app, event)
            updateParam(app, 'dist_square_delay', app.DelayEditField.Value);
        end

        % Value changed function: OffsetEditField_4
        function OffsetEditField_4ValueChanged(app, event)
            updateParam(app, 'dist_square_offset', app.OffsetEditField_4.Value);
        end

        % Value changed function: NoisePowerEditField
        function NoisePowerEditFieldValueChanged(app, event)
            updateParam(app, 'dist_noise_power', app.NoisePowerEditField.Value);
        end

        % Drop down opening function: COMportDropDown
        function COMportDropDownOpening(app, event)
            app.COMportDropDown.Items = extract(serialportlist('all'),digitsPattern); % Populate list of possible COM ports
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.ControlTab = uitab(app.TabGroup);
            app.ControlTab.Title = 'Control';
            app.GridLayout5 = uigridlayout(app.ControlTab);
            app.SystemConfigurationPanel = uipanel(app.GridLayout5);
            app.SystemConfigurationPanel.Title = 'System Configuration';
            app.GridLayout6 = uigridlayout(app.SystemConfigurationPanel);
            app.FeedbackDropDownLabel = uilabel(app.GridLayout6);
            app.FeedbackDropDownLabel.Text = 'Feedback';
            app.FeedbackDropDown = uidropdown(app.GridLayout6);
            app.FeedbackDropDown.Items = {'Open Loop', 'Closed Loop'};
            app.FeedbackDropDown.Value = 'Open Loop';
            app.SignalGeneratorPanel = uipanel(app.GridLayout5);
            app.SignalGeneratorPanel.Title = 'Signal Generator';
            app.GridLayout8 = uigridlayout(app.SignalGeneratorPanel);
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.InputSignalTypeDropDownLabel = uilabel(app.GridLayout9);
            app.InputSignalTypeDropDownLabel.Text = 'Input Signal Type';
            app.InputSignalTypeDropDown = uidropdown(app.GridLayout9);
            app.InputSignalTypeDropDown.Items = {'None', 'Constant', 'Sine', 'Square', 'Chirp', 'Arbitrary'};
            app.InputSignalTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @InputSignalTypeDropDownValueChanged, true);
            app.InputSignalTypeDropDown.Value = 'None';
            app.InputSignalSwitch = uiswitch(app.GridLayout9, 'rocker');
            app.InputSignalSwitch.ValueChangedFcn = createCallbackFcn(app, @InputSignalSwitchValueChanged, true);
            app.TabGroup_InputSignal = uitabgroup(app.GridLayout8);
            app.TabSigConstant = uitab(app.TabGroup_InputSignal);
            app.TabSigConstant.Title = 'Constant';
            app.GridLayout10 = uigridlayout(app.TabSigConstant);
            app.ValueEditFieldLabel = uilabel(app.GridLayout10);
            app.ValueEditFieldLabel.Text = 'Value';
            app.ConstantValueEditField = uieditfield(app.GridLayout10, 'numeric');
            app.ConstantValueEditField.ValueChangedFcn = createCallbackFcn(app, @ConstantValueEditFieldValueChanged, true);
            app.VoltsLabel = uilabel(app.GridLayout10);
            app.VoltsLabel.Text = 'Volts';
            app.TabSigSine = uitab(app.TabGroup_InputSignal);
            app.TabSigSine.Title = 'Sine';
            app.GridLayout10_2 = uigridlayout(app.TabSigSine);
            app.AmplitudeEditFieldLabel = uilabel(app.GridLayout10_2);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.GridLayout10_2, 'numeric');
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditFieldValueChanged, true);
            app.FrequencyEditFieldLabel = uilabel(app.GridLayout10_2);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.GridLayout10_2, 'numeric');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.OffsetEditFieldLabel = uilabel(app.GridLayout10_2);
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.OffsetEditField = uieditfield(app.GridLayout10_2, 'numeric');
            app.OffsetEditField.ValueChangedFcn = createCallbackFcn(app, @OffsetEditFieldValueChanged, true);
            app.VoltsLabel_3 = uilabel(app.GridLayout10_2);
            app.VoltsLabel_3.Text = 'Volts';
            app.HzLabel = uilabel(app.GridLayout10_2);
            app.HzLabel.Text = 'Hz';
            app.VoltsLabel_4 = uilabel(app.GridLayout10_2);
            app.VoltsLabel_4.Text = 'Volts';
            app.TabSigSquare = uitab(app.TabGroup_InputSignal);
            app.TabSigSquare.Title = 'Square';
            app.GridLayout10_3 = uigridlayout(app.TabSigSquare);
            app.AmplitudeEditField_2Label = uilabel(app.GridLayout10_3);
            app.AmplitudeEditField_2Label.Text = 'Amplitude';
            app.AmplitudeEditField_2 = uieditfield(app.GridLayout10_3, 'numeric');
            app.AmplitudeEditField_2.Limits = [0 Inf];
            app.AmplitudeEditField_2.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditField_2ValueChanged, true);
            app.PeriodEditFieldLabel = uilabel(app.GridLayout10_3);
            app.PeriodEditFieldLabel.Text = 'Period';
            app.PeriodEditField = uieditfield(app.GridLayout10_3, 'numeric');
            app.PeriodEditField.Limits = [0 Inf];
            app.PeriodEditField.ValueChangedFcn = createCallbackFcn(app, @PeriodEditFieldValueChanged, true);
            app.PeriodEditField.Value = 1;
            app.DutyEditFieldLabel = uilabel(app.GridLayout10_3);
            app.DutyEditFieldLabel.Text = 'Duty (%)';
            app.DutyEditField = uieditfield(app.GridLayout10_3, 'numeric');
            app.DutyEditField.Limits = [0 Inf];
            app.DutyEditField.ValueChangedFcn = createCallbackFcn(app, @DutyEditFieldValueChanged, true);
            app.DutyEditField.Value = 50;
            app.OffsetEditField_2Label = uilabel(app.GridLayout10_3);
            app.OffsetEditField_2Label.Text = 'Offset';
            app.OffsetEditField_2 = uieditfield(app.GridLayout10_3, 'numeric');
            app.OffsetEditField_2.ValueChangedFcn = createCallbackFcn(app, @OffsetEditField_2ValueChanged, true);
            app.VoltsLabel_5 = uilabel(app.GridLayout10_3);
            app.VoltsLabel_5.Text = 'Volts';
            app.SecondsLabel = uilabel(app.GridLayout10_3);
            app.SecondsLabel.Text = 'Seconds';
            app.Label = uilabel(app.GridLayout10_3);
            app.Label.Text = '%';
            app.VoltsLabel_6 = uilabel(app.GridLayout10_3);
            app.VoltsLabel_6.Text = 'Volts';
            app.TabSigChirp = uitab(app.TabGroup_InputSignal);
            app.TabSigChirp.Title = 'Chirp';
            app.GridLayout10_4 = uigridlayout(app.TabSigChirp);
            app.AmplitudeEditField_3Label = uilabel(app.GridLayout10_4);
            app.AmplitudeEditField_3Label.Text = 'Amplitude';
            app.AmplitudeEditField_3 = uieditfield(app.GridLayout10_4, 'numeric');
            app.AmplitudeEditField_3.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditField_3ValueChanged, true);
            app.StartFrequencyEditFieldLabel = uilabel(app.GridLayout10_4);
            app.StartFrequencyEditFieldLabel.Text = 'Start Frequency';
            app.StartFrequencyEditField = uieditfield(app.GridLayout10_4, 'numeric');
            app.StartFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @StartFrequencyEditFieldValueChanged, true);
            app.EndFrequencyEditFieldLabel = uilabel(app.GridLayout10_4);
            app.EndFrequencyEditFieldLabel.Text = 'End Frequency';
            app.EndFrequencyEditField = uieditfield(app.GridLayout10_4, 'numeric');
            app.EndFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @EndFrequencyEditFieldValueChanged, true);
            app.ChirpTimeEditFieldLabel = uilabel(app.GridLayout10_4);
            app.ChirpTimeEditFieldLabel.Text = 'Chirp Time';
            app.ChirpTimeEditField = uieditfield(app.GridLayout10_4, 'numeric');
            app.ChirpTimeEditField.ValueChangedFcn = createCallbackFcn(app, @ChirpTimeEditFieldValueChanged, true);
            app.VoltsLabel_7 = uilabel(app.GridLayout10_4);
            app.VoltsLabel_7.Text = 'Volts';
            app.HzLabel_2 = uilabel(app.GridLayout10_4);
            app.HzLabel_2.Text = 'Hz';
            app.HzLabel_3 = uilabel(app.GridLayout10_4);
            app.HzLabel_3.Text = 'Hz';
            app.SecondsLabel_2 = uilabel(app.GridLayout10_4);
            app.SecondsLabel_2.Text = 'Seconds';
            app.TabSigArbitrary = uitab(app.TabGroup_InputSignal);
            app.TabSigArbitrary.Title = 'Arbitrary';
            app.GridLayout9_2 = uigridlayout(app.GridLayout8);
            app.DisturbanceDropDownLabel = uilabel(app.GridLayout9_2);
            app.DisturbanceDropDownLabel.Text = 'Disturbance';
            app.DisturbanceDropDown = uidropdown(app.GridLayout9_2);
            app.DisturbanceDropDown.Items = {'None', 'Constant', 'Sine', 'Square', 'Noise', 'Arbitrary'};
            app.DisturbanceDropDown.ValueChangedFcn = createCallbackFcn(app, @DisturbanceDropDownValueChanged, true);
            app.DisturbanceDropDown.Value = 'None';
            app.DistrubanceSwitch = uiswitch(app.GridLayout9_2, 'rocker');
            app.DistrubanceSwitch.ValueChangedFcn = createCallbackFcn(app, @DistrubanceSwitchValueChanged, true);
            app.TabGroup_DistSig = uitabgroup(app.GridLayout8);
            app.TabDistConstant = uitab(app.TabGroup_DistSig);
            app.TabDistConstant.Title = 'Constant';
            app.GridLayout10_5 = uigridlayout(app.TabDistConstant);
            app.ValueEditField_2Label = uilabel(app.GridLayout10_5);
            app.ValueEditField_2Label.Text = 'Value';
            app.ValueEditField_2 = uieditfield(app.GridLayout10_5, 'numeric');
            app.ValueEditField_2.ValueChangedFcn = createCallbackFcn(app, @ValueEditField_2ValueChanged, true);
            app.openclosedLabel = uilabel(app.GridLayout10_5);
            app.openclosedLabel.Text = '0-1 open-closed';
            app.TabDistSine = uitab(app.TabGroup_DistSig);
            app.TabDistSine.Title = 'Sine';
            app.GridLayout10_6 = uigridlayout(app.TabDistSine);
            app.AmplitudeEditField_4Label = uilabel(app.GridLayout10_6);
            app.AmplitudeEditField_4Label.Text = 'Amplitude';
            app.AmplitudeEditField_4 = uieditfield(app.GridLayout10_6, 'numeric');
            app.AmplitudeEditField_4.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditField_4ValueChanged, true);
            app.FrequencyEditField_2Label = uilabel(app.GridLayout10_6);
            app.FrequencyEditField_2Label.Text = 'Frequency';
            app.FrequencyEditField_2 = uieditfield(app.GridLayout10_6, 'numeric');
            app.FrequencyEditField_2.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditField_2ValueChanged, true);
            app.OffsetEditField_3Label = uilabel(app.GridLayout10_6);
            app.OffsetEditField_3Label.Text = 'Offset';
            app.OffsetEditField_3 = uieditfield(app.GridLayout10_6, 'numeric');
            app.OffsetEditField_3.ValueChangedFcn = createCallbackFcn(app, @OffsetEditField_3ValueChanged, true);
            app.PhaseEditFieldLabel = uilabel(app.GridLayout10_6);
            app.PhaseEditFieldLabel.Text = 'Phase';
            app.PhaseEditField = uieditfield(app.GridLayout10_6, 'numeric');
            app.PhaseEditField.ValueChangedFcn = createCallbackFcn(app, @PhaseEditFieldValueChanged, true);
            app.openclosedLabel_2 = uilabel(app.GridLayout10_6);
            app.openclosedLabel_2.Text = '0-1 open-closed';
            app.HzLabel_4 = uilabel(app.GridLayout10_6);
            app.HzLabel_4.Text = 'Hz';
            app.openclosedLabel_3 = uilabel(app.GridLayout10_6);
            app.openclosedLabel_3.Text = '0-1 open-closed';
            app.DegreesLabel = uilabel(app.GridLayout10_6);
            app.DegreesLabel.Text = 'Degrees';
            app.TabDistSquare = uitab(app.TabGroup_DistSig);
            app.TabDistSquare.Title = 'Square';
            app.GridLayout10_7 = uigridlayout(app.TabDistSquare);
            app.AmplitudeEditField_5Label = uilabel(app.GridLayout10_7);
            app.AmplitudeEditField_5Label.Text = 'Amplitude';
            app.AmplitudeEditField_5 = uieditfield(app.GridLayout10_7, 'numeric');
            app.AmplitudeEditField_5.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditField_5ValueChanged, true);
            app.PeriodEditField_2Label = uilabel(app.GridLayout10_7);
            app.PeriodEditField_2Label.Text = 'Period';
            app.PeriodEditField_2 = uieditfield(app.GridLayout10_7, 'numeric');
            app.PeriodEditField_2.Limits = [1 Inf];
            app.PeriodEditField_2.ValueChangedFcn = createCallbackFcn(app, @PeriodEditField_2ValueChanged, true);
            app.PeriodEditField_2.Value = 1;
            app.DutyEditField_2Label = uilabel(app.GridLayout10_7);
            app.DutyEditField_2Label.Text = 'Duty (%)';
            app.DutyEditField_2 = uieditfield(app.GridLayout10_7, 'numeric');
            app.DutyEditField_2.Limits = [0.001 100];
            app.DutyEditField_2.ValueChangedFcn = createCallbackFcn(app, @DutyEditField_2ValueChanged, true);
            app.DutyEditField_2.Value = 50;
            app.OffsetEditField_4Label = uilabel(app.GridLayout10_7);
            app.OffsetEditField_4Label.Text = 'Offset';
            app.OffsetEditField_4 = uieditfield(app.GridLayout10_7, 'numeric');
            app.OffsetEditField_4.ValueChangedFcn = createCallbackFcn(app, @OffsetEditField_4ValueChanged, true);
            app.DelayEditFieldLabel = uilabel(app.GridLayout10_7);
            app.DelayEditFieldLabel.Text = 'Delay';
            app.DelayEditField = uieditfield(app.GridLayout10_7, 'numeric');
            app.DelayEditField.Limits = [0 Inf];
            app.DelayEditField.ValueChangedFcn = createCallbackFcn(app, @DelayEditFieldValueChanged, true);
            app.openclosedLabel_4 = uilabel(app.GridLayout10_7);
            app.openclosedLabel_4.Text = '0-1 open-closed';
            app.SecondsLabel_3 = uilabel(app.GridLayout10_7);
            app.SecondsLabel_3.Text = 'Seconds';
            app.Label_2 = uilabel(app.GridLayout10_7);
            app.Label_2.Text = '%';
            app.openclosedLabel_5 = uilabel(app.GridLayout10_7);
            app.openclosedLabel_5.Text = '0-1 open-closed';
            app.SecondsLabel_4 = uilabel(app.GridLayout10_7);
            app.SecondsLabel_4.Text = 'Seconds';
            app.TabDistNoise = uitab(app.TabGroup_DistSig);
            app.TabDistNoise.Title = 'Noise';
            app.GridLayout10_8 = uigridlayout(app.TabDistNoise);
            app.NoisePowerEditFieldLabel = uilabel(app.GridLayout10_8);
            app.NoisePowerEditFieldLabel.Text = 'Noise Power';
            app.NoisePowerEditField = uieditfield(app.GridLayout10_8, 'numeric');
            app.NoisePowerEditField.ValueChangedFcn = createCallbackFcn(app, @NoisePowerEditFieldValueChanged, true);
            app.VarianceEditFieldLabel = uilabel(app.GridLayout10_8);
            app.VarianceEditFieldLabel.Text = 'Variance';
            app.VarianceEditField = uieditfield(app.GridLayout10_8, 'numeric');
            app.GridLayout9_3 = uigridlayout(app.GridLayout8);
            app.MeasurementNoiseDropDownLabel = uilabel(app.GridLayout9_3);
            app.MeasurementNoiseDropDownLabel.Text = 'Measurement Noise';
            app.MeasurementNoiseDropDown = uidropdown(app.GridLayout9_3);
            app.MeasurementNoiseDropDown.Items = {'None'};
            app.MeasurementNoiseDropDown.Value = 'None';
            app.ConfigTab = uitab(app.TabGroup);
            app.ConfigTab.Title = 'Config';
            app.GridLayout12 = uigridlayout(app.ConfigTab);
            app.CalibrationSettingsPanel = uipanel(app.GridLayout12);
            app.CalibrationSettingsPanel.Title = 'Calibration Settings';
            app.GridLayout13 = uigridlayout(app.CalibrationSettingsPanel);
            app.Tank1scaleEditFieldLabel = uilabel(app.GridLayout13);
            app.Tank1scaleEditFieldLabel.Text = 'Tank 1 scale';
            app.Tank1scaleEditField = uieditfield(app.GridLayout13, 'numeric');
            app.Tank1scaleEditField.ValueChangedFcn = createCallbackFcn(app, @Tank1scaleEditFieldValueChanged, true);
            app.Tank1scaleEditField.Value = 0.05;
            app.Tank2scaleEditFieldLabel = uilabel(app.GridLayout13);
            app.Tank2scaleEditFieldLabel.Text = 'Tank 2 scale';
            app.Tank2scaleEditField = uieditfield(app.GridLayout13, 'numeric');
            app.Tank2scaleEditField.ValueChangedFcn = createCallbackFcn(app, @Tank2scaleEditFieldValueChanged, true);
            app.Tank2scaleEditField.Value = 0.05;
            app.Tank2offsetEditFieldLabel = uilabel(app.GridLayout13);
            app.Tank2offsetEditFieldLabel.Text = 'Tank 2 offset';
            app.Tank2offsetEditField = uieditfield(app.GridLayout13, 'numeric');
            app.Tank2offsetEditField.ValueChangedFcn = createCallbackFcn(app, @Tank2offsetEditFieldValueChanged, true);
            app.Tank1offsetEditFieldLabel = uilabel(app.GridLayout13);
            app.Tank1offsetEditFieldLabel.Text = 'Tank 1 offset';
            app.Tank1offsetEditField = uieditfield(app.GridLayout13, 'numeric');
            app.Tank1offsetEditField.ValueChangedFcn = createCallbackFcn(app, @Tank1offsetEditFieldValueChanged, true);
            app.CommunicationPanel = uipanel(app.GridLayout12);
            app.CommunicationPanel.Title = 'Communication';
            app.GridLayout14 = uigridlayout(app.CommunicationPanel);
            app.COMportDropDownLabel = uilabel(app.GridLayout14);
            app.COMportDropDownLabel.Text = 'COM port';
            app.COMportDropDown = uidropdown(app.GridLayout14);
            app.COMportDropDown.Items = {'1'};
            app.COMportDropDown.DropDownOpeningFcn = createCallbackFcn(app, @COMportDropDownOpening, true);
            app.COMportDropDown.ValueChangedFcn = createCallbackFcn(app, @COMportDropDownValueChanged, true);
            app.COMportDropDown.Value = '1';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.SimulationSettingsPanel = uipanel(app.GridLayout2);
            app.SimulationSettingsPanel.Title = 'Simulation Settings';
            app.GridLayout3 = uigridlayout(app.SimulationSettingsPanel);
            app.SimulationProgress = uisimprogress(app.GridLayout3);
            app.SimulationProgress.Simulation = app.Simulation;
            app.GridLayout7 = uigridlayout(app.GridLayout3);
            app.OperationModeDropDownLabel = uilabel(app.GridLayout7);
            app.OperationModeDropDownLabel.Text = 'Operation Mode';
            app.OperationModeDropDown = uidropdown(app.GridLayout7);
            app.OperationModeDropDown.Items = {'Simulated Plant', 'Actual Plant'};
            app.OperationModeDropDown.ValueChangedFcn = createCallbackFcn(app, @OperationModeDropDownValueChanged, true);
            app.OperationModeDropDown.Value = 'Actual Plant';
            app.IntervalStepsSpinnerLabel = uilabel(app.GridLayout7);
            app.IntervalStepsSpinnerLabel.Text = 'Interval Step (s)';
            app.IntervalStepsSpinner = uispinner(app.GridLayout7);
            app.IntervalStepsSpinner.Limits = [0 0.1];
            app.IntervalStepsSpinner.Value = 0.1;
            app.SimulationControls = uisimcontrols(app.GridLayout3);
            app.SimulationControls.Simulation = app.Simulation;
            app.TabGroup2 = uitabgroup(app.GridLayout2);
            app.PlotsTab = uitab(app.TabGroup2);
            app.PlotsTab.Title = 'Plots';
            app.GridLayout4 = uigridlayout(app.PlotsTab);
            app.GridLayout15 = uigridlayout(app.GridLayout4);
            app.TimeScalesSliderLabel = uilabel(app.GridLayout15);
            app.TimeScalesSliderLabel.Text = 'Time Scale (s)';
            app.TimeScalesSlider = uislider(app.GridLayout15);
            app.TimeScalesSlider.Limits = [100 1000];
            app.TimeScalesSlider.ValueChangedFcn = createCallbackFcn(app, @TimeScalesSliderValueChanged, true);
            app.TimeScalesSlider.Value = 100;
            app.TimeScope = uitimescope(app.GridLayout4);
            app.TimeScope.LegendNames = {'Coupled Tanks System/Controller:1'; 'Coupled Tanks System/Plant - Coupled Tanks/Bus Selector:1'; 'Coupled Tanks System/Plant - Coupled Tanks/Bus Selector:2'; 'Coupled Tanks System/Reference Signal:1'; 'Coupled Tanks System/Disturbance:1'};
            app.TimeScope.Title = 'Coupled Tanks System/Controller:1, Coupled Tanks System/Plant - Coupled Tanks/Bus Selector:1, Coupled Tanks System/Plant - Coupled Tanks/Bus Selector:2, Coupled Tanks System/Reference Signal:1, Coupled Tanks System/Disturbance:1';
            app.TimeScope.YLabel = 'Amplitude';
            app.TimeScope.XTimeSpan = 100;
            app.DataTab = uitab(app.TabGroup2);
            app.DataTab.Title = 'Data';
            app.GridLayout11 = uigridlayout(app.DataTab);
            app.UITable = uitable(app.GridLayout11);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % Bind initialization
    methods (Access = protected)

        function createBindings(app)

            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Coupled Tanks System/Controller:1'], app.TimeScope);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Coupled Tanks System/Disturbance:1'], app.TimeScope);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Coupled Tanks System/Plant - Coupled Tanks/Bus Selector:1'], app.TimeScope);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Coupled Tanks System/Plant - Coupled Tanks/Bus Selector:2'], app.TimeScope);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Coupled Tanks System/Reference Signal:1'], app.TimeScope);
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rtgui

            % Associate the Simulink Model
            app.Simulation = simulation('combined_mdl_wpanel');

            % Create UIFigure and components
            createComponents(app)

            % Create bindings
            createBindings(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end